classdef perceive_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TaskDropDown                matlab.ui.control.DropDown
        taskDropDownLabel           matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        stimsettingsTab             matlab.ui.container.Tab
        StimLabel                   matlab.ui.control.EditField
        OtherstimlabelvalueEditFieldLabel  matlab.ui.control.Label
        StimContacts                matlab.ui.control.EditField
        ContactsLabel               matlab.ui.control.Label
        StimLabelSpec               matlab.ui.control.EditField
        BurstdurationOnmsLabel_4    matlab.ui.control.Label
        StimLabelPulse              matlab.ui.control.EditField
        BurstdurationOnmsLabel_3    matlab.ui.control.Label
        StimLabelFreq               matlab.ui.control.EditField
        BurstdurationOnmsLabel_2    matlab.ui.control.Label
        advancedTab                 matlab.ui.container.Tab
        BurstDurOff                 matlab.ui.control.EditField
        BurstdurationOffmsEditFieldLabel  matlab.ui.control.Label
        BurstFreq                   matlab.ui.control.EditField
        BurstfreqHzEditFieldLabel   matlab.ui.control.Label
        BurstDurOn                  matlab.ui.control.EditField
        BurstdurationOnmsLabel      matlab.ui.control.Label
        saveandexitButton           matlab.ui.control.Button
        StimDropDown                matlab.ui.control.DropDown
        removeButton                matlab.ui.control.Button
        AdvancedoptionsLabel        matlab.ui.control.Label
        stimLabel                   matlab.ui.control.Label
        FilecurrentlyselectedLabel  matlab.ui.control.Label
        partQuestion                matlab.ui.control.Label
        runQuestion                 matlab.ui.control.Label
        partDropDown                matlab.ui.control.DropDown
        partDropDownLabel           matlab.ui.control.Label
        runDropDown                 matlab.ui.control.DropDown
        runDropDownLabel            matlab.ui.control.Label
        redoButton                  matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        filenameLabel               matlab.ui.control.Label
        saveandcontinueButton       matlab.ui.control.Button
        ContextMenu                 matlab.ui.container.ContextMenu
        testMenu                    matlab.ui.container.Menu
        stitchtogetherMenu          matlab.ui.container.Menu
    end

    properties (Access = public)
        MetaT
        localsettings
    end
    properties (Access = private)
        numberoffiles=0% Description
        iterator=1;
        initialLocalsettings struct
    end

    methods
        function nextrow(app)
            %%%% update the fields with the new line
            app.filenameLabel.Text = app.MetaT.perceiveFilename(app.iterator);
            app.TaskDropDown.Value = app.MetaT.task{app.iterator};
            app.runDropDown.Value = app.MetaT.run{app.iterator};
            app.partDropDown.Value = app.MetaT.part{app.iterator};
            app.StimDropDown.Value = char(extract(app.MetaT.acq{app.iterator}, ("StimOff"|"StimX"|"StimOnL"|"StimOnR"|"StimOnB"|"StimOnB"|"BurstL"|"BurstR"|"BurstB"|"StimOn1L"|"StimOn1aL"|"StimOn1bL"|"StimOn1cL"|"StimOn2L"|"StimOn2aL"|"StimOn2bL"|"StimOn2cL"|"StimOn1R"|"StimOn1aR"|"StimOn1bR"|"StimOn1cR"|"StimOn2R"|"StimOn2aR"|"StimOn2bR"|"StimOn2cR")));
            app.StimContacts.Value = char(regexp(app.MetaT.acq{app.iterator}, '([123][abc])+', 'match', 'once'));
            app.BurstDurOn.Value = char(extract(app.MetaT.acq{app.iterator}, 'DurOn' + digitsPattern()));
            app.BurstDurOff.Value = char(extract(app.MetaT.acq{app.iterator}, 'DurOff' + digitsPattern()));
            app.BurstFreq.Value = char(extract(app.MetaT.acq{app.iterator}, 'Freq' + digitsPattern()));
            app.StimLabelFreq.Value = char(extract(app.MetaT.acq{app.iterator},  digitsPattern() + 'Hz'));
            app.StimLabelPulse.Value = char(extract(app.MetaT.acq{app.iterator}, digitsPattern() + 'us'));
            app.StimLabelSpec.Value = char(extract(app.MetaT.acq{app.iterator}, ("Jit"|"InvPol"|"varHz")));

            %%%% update the button text
            if app.iterator == app.numberoffiles
                app.saveandcontinueButton.Text = 'Save and exit';
            else
                app.saveandcontinueButton.Text = 'Save and continue';
            end
            if strcmp(app.MetaT.remove{app.iterator},'REMOVE')
                app.removeButton.Text = 'keep';
            else
                app.removeButton.Text = 'remove';
            end

            %%%% update the color coding
            removeStyle(app.UITable)
            s = uistyle("BackgroundColor",[1 0.6 0.6]);
            row = [app.iterator;app.iterator;app.iterator];
            col = [2;5;7];
            addStyle(app.UITable,s,"cell",[row,col]);
            %%%% update the UITable.Data %%% VERY IMPORTANT
            app.UITable.Data = app.MetaT;
        end
        
        function saving_call(app)
            app.MetaT.task(app.iterator) = {app.TaskDropDown.Value};
            app.MetaT.run(app.iterator) = {app.runDropDown.Value};
            if ~strcmp({app.partDropDown.Value},'None')
                 app.MetaT.part(app.iterator) = {app.partDropDown.Value};
            else
                app.MetaT.part(app.iterator) = {''};
            end
            app.MetaT.acq(app.iterator) = {[app.StimDropDown.Value app.StimContacts.Value app.BurstDurOn.Value app.BurstDurOff.Value app.BurstFreq.Value app.StimLabelFreq.Value app.StimLabelPulse.Value app.StimLabelSpec.Value app.StimLabel.Value]};           
        end
    end

    methods (Access = private)
        function saveLocalsettingsToJson(app)
            defaultName = 'perceive_localsettings_custom.json';
            if isfield(app.localsettings,'name') && ~isempty(app.localsettings.name)
                defaultName = sprintf('perceive_localsettings_%s.json', lower(app.localsettings.name));
            end

            [fileName, folder] = uiputfile({'*.json','JSON files (*.json)'}, 'Save localsettings as JSON', defaultName);
            if isequal(fileName,0), return; end

            [~, base, ~] = fileparts(fileName);
            if ~startsWith(base, 'perceive_localsettings_')
                fileName = ['perceive_localsettings_' base '.json'];
            end

            s = struct( ...
                'taskItems', {app.localsettings.taskItems}, ...
                'stimItems', {app.localsettings.stimItems}, ...
                'check_followup_time', app.localsettings.check_followup_time, ...
                'check_gui_tasks',     app.localsettings.check_gui_tasks, ...
                'check_gui_med',       app.localsettings.check_gui_med, ...
                'convert2bids',        app.localsettings.convert2bids, ...
                'datafields', {app.localsettings.datafields} );

            app.validateLocalsettingsStruct(s);

            jsonText = jsonencode(s, 'PrettyPrint', true);
            fid = fopen(fullfile(folder, fileName),'w');
            if fid == -1
                uialert(app.UIFigure, 'Cannot open file for writing.', 'File Error', 'Icon','error');
                return;
            end
            fwrite(fid, jsonText, 'char');
            fclose(fid);

            uialert(app.UIFigure, sprintf('Saved localsettings to:\n%s', fullfile(folder,fileName)), 'Success');
        end
        function validateLocalsettingsStruct(app, data)
            % Required fields
            req = {'taskItems','stimItems','check_followup_time','check_gui_tasks','check_gui_med','convert2bids','datafields'};
            for k = 1:numel(req)
                if ~isfield(data, req{k})
                    error('Missing required field "%s" for localsettings.', req{k});
                end
            end

            % Types
            if ~iscell(data.taskItems) || ~all(cellfun(@ischar, data.taskItems))
                error('taskItems must be a cell array of strings.');
            end
            if ~iscell(data.stimItems) || ~all(cellfun(@ischar, data.stimItems))
                error('stimItems must be a cell array of strings.');
            end
            if ~islogical(data.check_followup_time) || ~isscalar(data.check_followup_time)
                error('check_followup_time must be a logical scalar.');
            end
            if ~islogical(data.check_gui_tasks) || ~isscalar(data.check_gui_tasks)
                error('check_gui_tasks must be a logical scalar.');
            end
            if ~islogical(data.check_gui_med) || ~isscalar(data.check_gui_med)
                error('check_gui_med must be a logical scalar.');
            end
            if ~islogical(data.convert2bids) || ~isscalar(data.convert2bids)
                error('convert2bids must be a logical scalar.');
            end
            if ~iscell(data.datafields) || ~all(cellfun(@ischar, data.datafields))
                error('datafields must be a cell array of strings.');
            end

            % Cross-field rule: BrainSenseTimeDomain and BrainSenseLfp must co-exist
            hasTD  = any(strcmpi(data.datafields, 'BrainSenseTimeDomain'));
            hasLFP = any(strcmpi(data.datafields, 'BrainSenseLfp'));
            if xor(hasTD, hasLFP)
                error('Invalid datafields: "BrainSenseTimeDomain" and "BrainSenseLfp" must both be present or both absent.');
            end
        end

        function newItem = promptNewItemModal(app, itemList, itemType)
            % itemList = cell array of existing items
            % itemType = string, e.g. 'Task' or 'Stim'

            % Create a taller modal dialog
            d = uifigure('Name',['Add New ' itemType], 'WindowStyle','modal');
            d.Position(3:4) = [400 200];  % wider and taller

            % SAFETY: ensure uiwait is always released
            d.CloseRequestFcn = @(src,evt) cancelPressed();

            % Label and edit field
            uilabel(d, 'Text', ...
                ['Enter new ' lower(itemType) ' name (CamelCase, letters/numbers only):'], ...
                'Position', [20 140 360 22]);
            ef = uieditfield(d, 'text', 'Position', [20 110 360 22]);

            % OK and Cancel buttons only
            uibutton(d, 'Text', 'OK', 'Position', [200 60 70 24], ...
                'ButtonPushedFcn', @(btn,evt) okPressed());
            uibutton(d, 'Text', 'Cancel', 'Position', [280 60 70 24], ...
                'ButtonPushedFcn', @(btn,evt) cancelPressed());

            % Default return
            newItem = '';
            uiwait(d);

            % Nested callbacks
            function okPressed()
                candidate = strtrim(ef.Value);

                % Validation: alphanumeric only
                if isempty(regexp(candidate,'^[A-Za-z0-9]+$','once'))
                    uiconfirm(d, ...
                        'Invalid name. Use only letters and numbers, no spaces or special characters. Try CamelCase.', ...
                        ['Invalid ' itemType], 'Options', {'OK'}, 'Icon', 'error');
                    return;
                end

                % Validation: no duplicates
                if any(strcmp(itemList, candidate))
                    uiconfirm(d, ...
                        ['This ' lower(itemType) ' already exists in the list.'], ...
                        ['Duplicate ' itemType], 'Options', {'OK'}, 'Icon', 'warning');
                    return;
                end

                % Passed validation
                newItem = candidate;
                uiresume(d);
                delete(d);
            end

            function cancelPressed()
                newItem = '';
                uiresume(d);
                delete(d);
            end
        end
        function changed = hasLocalsettingsChanges(app)
            changed = false;

            % Normalize to cellstr and sorted for robust comparison
            curTasks = app.localsettings.taskItems(:);
            baseTasks = app.initialLocalsettings.taskItems(:);
            curStims = app.localsettings.stimItems(:);
            baseStims = app.initialLocalsettings.stimItems(:);

            % Compare lengths first
            if numel(curTasks) ~= numel(baseTasks) || numel(curStims) ~= numel(baseStims)
                changed = true; return;
            end

            % Compare content ignoring order
            if ~isequal(sort(curTasks), sort(baseTasks)) || ~isequal(sort(curStims), sort(baseStims))
                changed = true; return;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MetaT, localsettings)
            if ~exist('MetaT', 'var')
                warning('now loading an example MetaT.mat')
                load('Example_dataset.mat', 'MetaT')
            end
            %if ~exist('Settings', 'var')
            %    warning('now loading default settings from Default_settings.mat')
            %    load('Default_settings.mat', 'Settings')
            %end
            %app.TaskDropDown.Items = {'Rest', 'CustomLabel', 'TASK1', 'TASK2', 'TASK3', 'TASK4', 'TASK5', 'TASK6', 'TASK7', 'TASK8', 'TASK9', 'XXXXX'};
                
            % Populate TaskDropDown with institution-specific taskItems
            app.TaskDropDown.Items = [localsettings.taskItems(:); {'Add New Task'}];
            if ~isempty(localsettings.taskItems)
                app.TaskDropDown.Value = localsettings.taskItems{1};
            end
            

            % Populate StimDropDown with institution-specific stimItems
            app.StimDropDown.Items = [localsettings.stimItems(:); {'Add New Stim'}];
            if ~isempty(localsettings.stimItems)
                app.StimDropDown.Value = localsettings.stimItems{1};
            end
            
            % In StartupFcn
            app.UIFigure.CloseRequestFcn = @(src,event) ...
                set(app.saveandexitButton,'UserData','closed');
            
            app.localsettings = localsettings;
            app.initialLocalsettings = app.localsettings;
            app.MetaT = MetaT;
            app.UITable.Data = app.MetaT;
            app.UITable.ColumnWidth = 'fit'; 
            app.numberoffiles=length(app.MetaT.perceiveFilename);
            nextrow(app);
        end

        % Button pushed function: saveandcontinueButton
        function saveandcontinueButtonPushed(app, event)
            if app.iterator ~= app.numberoffiles
                saving_call(app);
                app.iterator = app.iterator + 1;
                nextrow(app);
            else
                saveandexitButtonPushed(app, event)
            end
        end

        % Button pushed function: saveandexitButton
        function saveandexitButtonPushed(app, event)
            %%% SAVE last changes
            saving_call(app);
            
            %%% UPDATE entire table
            for i = 1:app.numberoffiles
                    
                task=regexp(app.MetaT.perceiveFilename{i},'(?<=task-).*(?=_acq)','match');
                task=['task-' task{1}];
                app.MetaT.perceiveFilename{i}=strrep(app.MetaT.perceiveFilename{i},task,['task-' app.MetaT.task{i}]);
                run=regexp(app.MetaT.perceiveFilename{i},'(?<=run-)([0-9]*)','match');
                run = ['run-' run{1}];
                app.MetaT.perceiveFilename{i}=strrep(app.MetaT.perceiveFilename{i},run,['run-' app.MetaT.run{i}]);
                acq=regexp(app.MetaT.perceiveFilename{i},'(?<=acq-).*(?=_mod)','match');
                acq=['acq-' acq{1}];
                app.MetaT.perceiveFilename{i}=strrep(app.MetaT.perceiveFilename{i},acq,['acq-' app.MetaT.acq{i}]);
                if app.MetaT.part{i}
                    if contains(app.MetaT.perceiveFilename{i}, 'part')
                        part=regexp(app.MetaT.perceiveFilename{i},'(?<=part-)[1-9]*','match');
                        part=['part-' part{1}];
                        app.MetaT.perceiveFilename{i}=strrep(app.MetaT.perceiveFilename{i},part,['part-' app.MetaT.part{i}]);
                    else
                        app.MetaT.perceiveFilename{i}=strrep(app.MetaT.perceiveFilename{i},'.mat',['_part-' app.MetaT.part{i} '.mat']);
                    end
                end
            end
            
            %%%% preset the check conditions before going to EXIT
            problem_parts  = false;
            concat         = false;
            problemfilenr  = 0;
            errors         = {};
            maskKeep = ~strcmp(app.MetaT.remove,'REMOVE');   % only keep non-removed rows

           %%%%%%%% CHECK FOR CONCATENATION AND PART NR %%%%%%%%

           % Extract run number
           runNum = regexp(app.MetaT.perceiveFilename, '(?<=_run-)\d+', 'match', 'once');
           runNum = str2double(runNum);

           % Extract mod label (letters only)
           modLabel = regexp(app.MetaT.perceiveFilename, '(?<=_mod-)[A-Za-z]+', 'match', 'once');

           % Build grouping key: modLabel + run number
           groupKey = strcat(modLabel, "_run_", string(runNum));

           % Extract part numbers
           allPartNums = str2double(app.MetaT.part);
           validPartMask = ~isnan(allPartNums);

           % Loop over each stitch group
           uniqueGroups = unique(groupKey(maskKeep));

           for g = uniqueGroups'
               % Select files in this group
               runMask = strcmp(groupKey, g) & maskKeep;

               % Extract part numbers for this group
               parts = allPartNums(runMask & validPartMask);

               % Ignore stitched files with no part label
               if isempty(parts)
                   continue
               end

               % Sort parts
               parts = sort(parts);

               % RULE 1: Part sequence must start at 1
               if parts(1) ~= 1
                   idx = find(runMask & validPartMask, 1, 'first');
                   fname = app.MetaT.perceiveFilename{idx};

                   errors{end+1} = sprintf( ...
                       ['\nInvalid part sequence for file: %s\n' ...
                       'Part numbers must start at 1, but found part %d.'], ...
                       fname, parts(1));

                   problem_parts = true;
                   problemfilenr = idx;
                   break
               end

               % RULE 2: Check for missing parts
               expected = parts(1):parts(end);
               missing = expected(~ismember(expected, parts));

               if ~isempty(missing)
                   % Pick the closest existing part to the missing one
                   refMissing = missing(1);
                   candidateParts = parts;
                   [~, minIdxLocal] = min(abs(candidateParts - refMissing));

                   idxCandidates = find(runMask & validPartMask);
                   idx = idxCandidates(minIdxLocal);

                   fname = app.MetaT.perceiveFilename{idx};

                   errors{end+1} = sprintf( ...
                       '\nMissing part(s) %s near file: %s', ...
                       num2str(missing), fname);

                   problem_parts = true;
                   problemfilenr = idx;
                   break
               end

               % RULE 3: If part 1 exists, part 2 must exist
               if any(parts == 1) && ~any(parts == 2)
                   idx = find(runMask & strcmp(app.MetaT.part, '1'), 1, 'first');
                   fname = app.MetaT.perceiveFilename{idx};

                   errors{end+1} = sprintf('\nMissing part 2 for file: %s', fname);
                   problem_parts = true;
                   problemfilenr = idx;
                   break
               end

               concat = true;
           end


            % --- Build duplicate-check keys (ignoring part blocks, keeping mod numbers) ---
            rawNames = app.MetaT.perceiveFilename(maskKeep);

            % Remove only the part block: "_part-N"
            dupKey = regexprep(rawNames, '_part-\d+', '');

            % Now check uniqueness on dupKey
            [uniqKeys,~,idx] = unique(dupKey,'stable');
            dupKeys = uniqKeys(accumarray(idx,1) > 1);

            if ~isempty(dupKeys)
                % Find first offending row in original table
                dupMask = ismember(dupKey, dupKeys) & maskKeep;
                problemfilenr = find(dupMask, 1, 'first');

                realName = app.MetaT.perceiveFilename{problemfilenr};
                errors{end+1} = sprintf('\nNon-unique filename detected: %s', realName);

                problem_parts = true;
            end


            % --- Concatenation confirmation ---
            if ~problem_parts && concat

                %%%%%%%% DIALOG WINDOWS PER STITCH GROUP %%%%%%%%

                % Extract run number
                runNum = regexp(app.MetaT.perceiveFilename, '(?<=_run-)\d+', 'match', 'once');
                runNum = str2double(runNum);

                % Extract mod label
                modLabel = regexp(app.MetaT.perceiveFilename, '(?<=_mod-)[A-Za-z]+', 'match', 'once');

                % Build grouping key
                groupKey = strcat(modLabel, "_run_", string(runNum));

                % Extract part numbers
                allPartNums = str2double(app.MetaT.part);
                validPartMask = ~isnan(allPartNums);

                % Loop over each stitch group
                uniqueGroups = unique(groupKey(maskKeep));

                for g = uniqueGroups'
                    % Files in this group
                    runMask = strcmp(groupKey, g) & maskKeep;

                    % Only files with part numbers
                    groupPartMask = runMask & validPartMask;

                    % Collect filenames for this group
                    filesWithPart = app.MetaT.perceiveFilename(groupPartMask);

                    % If this group has no part files, skip
                    if isempty(filesWithPart)
                        continue
                    end

                    % Build readable confirmation text
                    fileList = sprintf('  %s\n', filesWithPart{:});
                    confirmText = sprintf("Concatenate the following files?\n\n%s", fileList);

                    % Show dialog for THIS group only
                    answer = questdlg(confirmText, ...
                        sprintf('Stitching group %s', g), ...
                        'Proceed', 'Abort', 'Proceed');

                    if strcmp(answer, 'Abort')
                        errors{end+1} = sprintf('Concatenation aborted by user for group %s.', g);
                        problem_parts = true;
                        break
                    end
                end

            end


            % --- Show one dialog if errors ---
            if ~isempty(errors)
                errordlg(strjoin(errors,newline),'File Errors');
                if problemfilenr
                    app.iterator = problemfilenr;
                else
                    app.iterator = 1;
                end
                app.UITable.Data = app.MetaT;
                nextrow(app);
                app.saveandcontinueButton.Text = 'Save and continue';
            else %%% EXIT THE GUI HERE
                % After your error checks and before setting UserData='Done'
                if isempty(errors)
                    % Offer saving Task/Stim changes to JSON if anything changed
                    if hasLocalsettingsChanges(app)
                        choice = uiconfirm(app.UIFigure, ...
                            'Task/Stim settings changed. Save to a JSON config file?', ...
                            'Save Localsettings', ...
                            'Options', {'Yes','No'}, 'DefaultOption', 2, 'Icon', 'question');

                        if strcmp(choice,'Yes')
                            saveLocalsettingsToJson(app);
                        end
                    end

                    app.saveandexitButton.UserData = 'Done';%%% continue to main app  
                end
            end
        end

        % Button pushed function: redoButton
        function redoButtonPushed(app, event)
            app.saveandcontinueButton.Text = 'Save and continue';
            app.iterator = 1;
            nextrow(app);
        end

        % Button pushed function: removeButton
        function removeButtonPushed(app, event)
            if strcmp(app.removeButton.Text,'remove')
                app.TaskDropDown.Value = 'XXXXX';
                app.runDropDown.Value = '999';
                app.MetaT.remove{app.iterator} = 'REMOVE';
                saveandcontinueButtonPushed(app, event)
            else
                app.TaskDropDown.Value = 'TASK1';
                app.runDropDown.Value = '1';
                app.MetaT.remove{app.iterator} = 'keep';
                %saveandcontinueButtonPushed(app, event)
                saving_call(app);
                nextrow(app);
                app.removeButton.Text = 'remove';
            end
        end

        % Value changed function: runDropDown
        function runDropDownValueChanged(app, event)
            valStr = app.runDropDown.Value;          % user input as string
            valNum = str2double(valStr);          % convert to number
        
            % Check validity: must be integer between 1 and 1000
            if isnan(valNum) || valNum ~= floor(valNum) || valNum < 1 || valNum > 1000
                uialert(app.UIFigure, ...
                    'Please enter a whole number between 1 and 1000.', ...
                    'Invalid Input');
                % Reset to a safe default
                app.runDropDown.Value = '1';
            else
                % Normalize formatting (remove leading zeros, decimals)
                app.runDropDown.Value = num2str(valNum);
            end
        end

        % Value changed function: TaskDropDown
        function TaskDropDownValueChanged(app, event)
            if strcmp(app.TaskDropDown.Value, 'Add New Task')
                candidate = promptNewItemModal(app, app.localsettings.taskItems, 'Task');

                if isempty(candidate)
                    app.TaskDropDown.Value = app.TaskDropDown.Items{1};
                    return;
                end

                app.localsettings.taskItems{end+1} = candidate;
                app.TaskDropDown.Items = [app.localsettings.taskItems(:); {'Add New Task'}];
                app.TaskDropDown.Value = candidate;
            end
        end

        % Value changed function: StimDropDown
        function StimDropDownValueChanged(app, event)
            if strcmp(app.StimDropDown.Value, 'Add New Stim')
                % Use the generalized modal prompt
                candidate = promptNewItemModal(app, app.localsettings.stimItems, 'Stim');

                % If user canceled → reset dropdown
                if isempty(candidate)
                    app.StimDropDown.Value = app.StimDropDown.Items{1};
                    return;
                end

                % Append to localsettings.stimItems
                app.localsettings.stimItems{end+1} = candidate;

                % Refresh dropdown items (always add "Add New Stim" at the end)
                app.StimDropDown.Items = [app.localsettings.stimItems(:); {'Add New Stim'}];
                app.StimDropDown.Value = candidate;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.saveandcontinueButton = uibutton(app.UIFigure, 'push');
            app.saveandcontinueButton.ButtonPushedFcn = createCallbackFcn(app, @saveandcontinueButtonPushed, true);
            app.saveandcontinueButton.Text = 'save and continue';
            app.filenameLabel = uilabel(app.UIFigure);
            app.filenameLabel.Text = 'filename';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'''report'''; '''perceiveFilename'''; '''session'''; '''condition'''; '''task'''; '''contacts'''; '''run'''; '''part'''; '''acq'''; '''remove?'''};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.Multiselect = 'off';
            app.UITable.Enable = 'off';
            app.redoButton = uibutton(app.UIFigure, 'push');
            app.redoButton.ButtonPushedFcn = createCallbackFcn(app, @redoButtonPushed, true);
            app.redoButton.Text = 'redo';
            app.runDropDownLabel = uilabel(app.UIFigure);
            app.runDropDownLabel.Text = 'run';
            app.runDropDown = uidropdown(app.UIFigure);
            app.runDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.runDropDown.Editable = 'on';
            app.runDropDown.ValueChangedFcn = createCallbackFcn(app, @runDropDownValueChanged, true);
            app.runDropDown.Tooltip = {''};
            app.runDropDown.Value = '1';
            app.partDropDownLabel = uilabel(app.UIFigure);
            app.partDropDownLabel.Text = 'part';
            app.partDropDown = uidropdown(app.UIFigure);
            app.partDropDown.Items = {'None', '', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.partDropDown.Tooltip = {''};
            app.partDropDown.Value = 'None';
            app.runQuestion = uilabel(app.UIFigure);
            app.runQuestion.Tooltip = {'A run number is always added, in contrast with the partnumber.'; ''; 'The runnumber is always 1 by default: for a new recording of the same subject, same condition, same task, same acquisition, increase the runnumber by 1.'; ''; 'For an interrupted recording, keep the runnumber equal and add or increase the part number instead (e.g. run 1 part 1, run 1 part 2).'; ''; 'See also https://bids.neuroimaging.io/'};
            app.runQuestion.Text = '?';
            app.partQuestion = uilabel(app.UIFigure);
            app.partQuestion.Tooltip = {'The partnumber is only applicable for recordings that were interrupted due to a technical issue.'; ''; 'partnumber of 0/None, means that the recording is complete (The partnumber will not appear).'; ''; 'Partnumber 1 and 2 means those recordings with the same task and run number needs to be stiched together [in that order] due to an interrumption. For now, parts until 9 interruptions can be stitched together.'; ''; 'Example:'; 'sub-002_ses-Fu12mMedOff02_task-Rest_acq-StimOff_mod-BrainSenseBip_run-1_part-1.mat'; 'sub-002_ses-Fu12mMedOff02_task-Rest_acq-StimOff_mod-BrainSenseBip_run-1_part-2.mat'; ''; 'will be concatenated to:'; 'sub-002_ses-Fu12mMedOff02_task-Rest_acq-StimOff_mod-BrainSenseBip_run-1.mat'; ''; 'The files of the 2 parts and the file of the concatenation are stored.'; ''; 'See also https://bids.neuroimaging.io/'};
            app.partQuestion.Text = '?';
            app.FilecurrentlyselectedLabel = uilabel(app.UIFigure);
            app.FilecurrentlyselectedLabel.Text = 'File currently selected:';
            app.stimLabel = uilabel(app.UIFigure);
            app.stimLabel.Text = 'stim';
            app.AdvancedoptionsLabel = uilabel(app.UIFigure);
            app.AdvancedoptionsLabel.Text = 'Advanced options:';
            app.removeButton = uibutton(app.UIFigure, 'push');
            app.removeButton.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.removeButton.Text = 'remove';
            app.StimDropDown = uidropdown(app.UIFigure);
            app.StimDropDown.Items = {'StimOff', 'StimOnR', 'StimOnL', 'StimOnB', 'StimX', 'BurstL', 'BurstR', 'BurstB', 'StimOn1L', 'StimOn1aL', 'StimOn1bL', 'StimOn1cL', 'StimOn2L', 'StimOn2aL', 'StimOn2bL', 'StimOn2cL', 'StimOn1R', 'StimOn1aR', 'StimOn1bR', 'StimOn1cR', 'StimOn2R', 'StimOn2aR', 'StimOn2bR', 'StimOn2cR'};
            app.StimDropDown.ValueChangedFcn = createCallbackFcn(app, @StimDropDownValueChanged, true);
            app.StimDropDown.Tooltip = {''};
            app.StimDropDown.Value = 'StimOff';
            app.saveandexitButton = uibutton(app.UIFigure, 'push');
            app.saveandexitButton.ButtonPushedFcn = createCallbackFcn(app, @saveandexitButtonPushed, true);
            app.saveandexitButton.Text = {'save'; 'and exit'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.stimsettingsTab = uitab(app.TabGroup);
            app.stimsettingsTab.Title = 'stim settings';
            app.BurstdurationOnmsLabel_2 = uilabel(app.stimsettingsTab);
            app.BurstdurationOnmsLabel_2.Text = 'Freq (Hz)';
            app.StimLabelFreq = uieditfield(app.stimsettingsTab, 'text');
            app.StimLabelFreq.Placeholder = '125Hz';
            app.BurstdurationOnmsLabel_3 = uilabel(app.stimsettingsTab);
            app.BurstdurationOnmsLabel_3.Text = 'Pulse width ';
            app.StimLabelPulse = uieditfield(app.stimsettingsTab, 'text');
            app.StimLabelPulse.Placeholder = '60us';
            app.BurstdurationOnmsLabel_4 = uilabel(app.stimsettingsTab);
            app.BurstdurationOnmsLabel_4.Text = 'Spec';
            app.StimLabelSpec = uieditfield(app.stimsettingsTab, 'text');
            app.StimLabelSpec.Placeholder = 'InvPol / Jit';
            app.ContactsLabel = uilabel(app.stimsettingsTab);
            app.ContactsLabel.Text = 'Contacts';
            app.StimContacts = uieditfield(app.stimsettingsTab, 'text');
            app.StimContacts.Placeholder = 'a1a2a3b1b2b3c1c2c3';
            app.OtherstimlabelvalueEditFieldLabel = uilabel(app.stimsettingsTab);
            app.OtherstimlabelvalueEditFieldLabel.Text = 'Other stim label - value';
            app.StimLabel = uieditfield(app.stimsettingsTab, 'text');
            app.StimLabel.Placeholder = 'Lab000';
            app.advancedTab = uitab(app.TabGroup);
            app.advancedTab.Title = 'advanced';
            app.BurstdurationOnmsLabel = uilabel(app.advancedTab);
            app.BurstdurationOnmsLabel.Text = 'Burst duration On (ms)';
            app.BurstDurOn = uieditfield(app.advancedTab, 'text');
            app.BurstDurOn.Placeholder = 'DurOn000';
            app.BurstfreqHzEditFieldLabel = uilabel(app.advancedTab);
            app.BurstfreqHzEditFieldLabel.Text = 'Burst freq (Hz)';
            app.BurstFreq = uieditfield(app.advancedTab, 'text');
            app.BurstFreq.Placeholder = 'Freq000';
            app.BurstdurationOffmsEditFieldLabel = uilabel(app.advancedTab);
            app.BurstdurationOffmsEditFieldLabel.Text = 'Burst duration Off (ms)';
            app.BurstDurOff = uieditfield(app.advancedTab, 'text');
            app.BurstDurOff.Placeholder = 'DurOff000';
            app.taskDropDownLabel = uilabel(app.UIFigure);
            app.taskDropDownLabel.Text = 'task';
            app.TaskDropDown = uidropdown(app.UIFigure);
            app.TaskDropDown.Items = {'Rest', 'RestTaptest', 'RestTapCont', 'RestFace', 'FingerTap', 'FingerTapL', 'FingerTapR', 'Rota', 'UPDRS', 'MovArtWalk', 'MovArtStand', 'MovArtHead', 'MovArtArms', 'MonRevDyt', 'FTGRampUpThres', 'FTGFastRamp125Hz', 'FTG110Hz', 'FTGBilateral', 'FTG145Hz', 'MID', 'SMTS', 'MSST', 'DualTask', 'Sync1', 'Sync2', 'RestTapBlock', 'RestTapMonoRev', 'RestTapMonoRevNoBreaks', 'RestMovBlock', 'RestMovMonoRev', 'RestMovMonoRevNoBreaks', 'RestMonoRev', 'RestMonoRevNoBreaks', 'Entrainment', 'TASK1', 'TASK2', 'TASK3', 'TASK4', 'TASK5', 'TASK6', 'TASK7', 'TASK8', 'TASK9', 'TASK10', 'TASK11', 'TASK12', 'TASK13', 'TASK14', 'TASK15', 'XXXXX', 'Add New Task'};
            app.TaskDropDown.ValueChangedFcn = createCallbackFcn(app, @TaskDropDownValueChanged, true);
            app.TaskDropDown.Value = 'Rest';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.testMenu = uimenu(app.ContextMenu);
            app.testMenu.Text = 'test';
            app.stitchtogetherMenu = uimenu(app.ContextMenu);
            app.stitchtogetherMenu.Text = 'stitch together';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = perceive_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end